classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        RAWDATATab              matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
        mVDATATab               matlab.ui.container.Tab
        UIAxes2_4               matlab.ui.control.UIAxes
        FFTDATATab              matlab.ui.container.Tab
        UIAxes2_5               matlab.ui.control.UIAxes
        OutlierOptionDropDown   matlab.ui.control.DropDown
        REMOVEOUTLIERButton     matlab.ui.control.Button
        FFTButton               matlab.ui.control.Button
        TOmVButton              matlab.ui.control.Button
        mVDATALabel             matlab.ui.control.Label
        TEXTFILEDATALabel       matlab.ui.control.Label
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        UITable_2               matlab.ui.control.Table
        UITable                 matlab.ui.control.Table
        READTXTFILEButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        B % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

%READ TEXT FILE KNOP
%KIES EEN FILE IN DATA/PP01 FOLDER
%TOON FILE TITEL IN EDIT FIELD
%TOON FILE CONTENT IN TEXTAREA

[filename,path]=uigetfile("DATA/PP01/*.txt");
app.FilenameEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
loadedData = readmatrix(pathToFile);
%vars = {'nSeq','DI','CH1','CH2','CH3','CH4','CH5'};
%app.UITable.ColumnName = vars;
app.UITable.Data = loadedData;
fclose(fileID);
figure(app.UIFigure);

%STAP 2
channelData = loadedData(:,3:end);
app.TOmVButton.Enable = 'on';
app.B = ((channelData ./ (2^16)) - (0.5)) * 3;


%STAP 3
Fs = 1000;            % Sampling frequency                    
T = 1/Fs;             % Sampling period       
L = length(app.B);    % Length of signal
t = (0:L)*T;        % Time vector

%S = 0.7*sin(2*pi*50*t) + sin(2*pi*120*t);

X = app.B + 2*randn(size(t));

tiledlayout(3,1);
nexttile;

plot(1000*t(1:L),X(1:L))
title('Before FFT')
xlabel('t (milliseconds)')
ylabel('B(t)')


Y = fft(X);

P2 = abs(Y/L);
P1 = P2(1:L/2+1);
P1(2:end-1) = 2*P1(2:end-1);

f = Fs*(0:(L/2))/L;

nexttile;
plot(f,P1) 
title('After FFT X')
xlabel('f (Hz)')
ylabel('|P1(f)|')
%=================
X = app.B(:,3);

Y = fft(X);

P2 = abs(Y/L);
P1 = P2(1:L/2+1);
P1(2:end-1) = 2*P1(2:end-1);

f = Fs*(0:(L/2))/L;

nexttile;
plot(f,P1) 
title('After FFT B')
xlabel('f (Hz)')
ylabel('|P1(f)|')

% C = fft(app.B);

% piek1 = (sin(20*pi*app.B) + 5*sin(100*pi*app.B));
% piek2 = (sin(20*pi*C) + 5*sin(100*pi*C));

% tiledlayout(2,1);
% 
% nexttile;
% plot(app.B(:,1));
% title('B data');
% nexttile;
% plot(C(:,1));
% title('C data');

%STAP 4
% tiledlayout(5,1);
% Outlier1 = rmoutliers(B,"mean","ThresholdFactor",1);
% Outlier2 = rmoutliers(B,"mean","ThresholdFactor",2);
% Outlier3 = rmoutliers(B,"mean","ThresholdFactor",3);
% 
% 
% nexttile;
% plot(B);
% title('mV data');
% nexttile;
% plot(C);
% title('FFT data');
% nexttile;
% plot(Outlier1);
% title('Outlier1 data');
% nexttile;
% plot(Outlier2);
% title('Outlier2 data');
% nexttile;
% plot(Outlier3);
% title('Outlier3 data');

        end

        % Callback function
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end

        % Button pushed function: TOmVButton
        function TOmVButtonPushed(app, event)
                app.UITable_2.Data = app.B;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'nSeq'; 'DI'; 'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'CH1'; 'CH2'; 'CH3'; 'CH4'; 'CH5'};
            app.UITable_2.RowName = {};
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.TEXTFILEDATALabel = uilabel(app.UIFigure);
            app.TEXTFILEDATALabel.Text = 'TEXT  FILE DATA';
            app.mVDATALabel = uilabel(app.UIFigure);
            app.mVDATALabel.Text = 'mV DATA';
            app.TOmVButton = uibutton(app.UIFigure, 'push');
            app.TOmVButton.ButtonPushedFcn = createCallbackFcn(app, @TOmVButtonPushed, true);
            app.TOmVButton.Enable = 'off';
            app.TOmVButton.Text = 'TO mV';
            app.FFTButton = uibutton(app.UIFigure, 'push');
            app.FFTButton.Enable = 'off';
            app.FFTButton.Text = 'FFT';
            app.REMOVEOUTLIERButton = uibutton(app.UIFigure, 'push');
            app.REMOVEOUTLIERButton.Enable = 'off';
            app.REMOVEOUTLIERButton.Text = 'REMOVE OUTLIER';
            app.OutlierOptionDropDown = uidropdown(app.UIFigure);
            app.OutlierOptionDropDown.Items = {'1', '2', '3'};
            app.OutlierOptionDropDown.Enable = 'off';
            app.OutlierOptionDropDown.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RAWDATATab = uitab(app.TabGroup);
            app.RAWDATATab.Title = 'RAW DATA';
            app.UIAxes2 = uiaxes(app.RAWDATATab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.mVDATATab = uitab(app.TabGroup);
            app.mVDATATab.Title = 'mV DATA';
            app.UIAxes2_4 = uiaxes(app.mVDATATab);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.FFTDATATab = uitab(app.TabGroup);
            app.FFTDATATab.Title = 'FFT DATA';
            app.UIAxes2_5 = uiaxes(app.FFTDATATab);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end